classdef BeatDetectionDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewSizeSecondsEditField     matlab.ui.control.EditField
        PreviewSizeSecondsEditFieldLabel  matlab.ui.control.Label
        XlimSlider                      matlab.ui.control.Slider
        XlimSliderLabel                 matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        HeartRateCalculationSettingsPanel  matlab.ui.container.Panel
        ConvolutionWindowSizeEditField  matlab.ui.control.EditField
        ConvolutionWindowSizeEditFieldLabel  matlab.ui.control.Label
        BeatDetectionSettingsPanel      matlab.ui.container.Panel
        ThresholdWindowsecEditField     matlab.ui.control.EditField
        ThresholdWindowsecLabel         matlab.ui.control.Label
        BeatMergeToleranceEditField     matlab.ui.control.EditField
        BeatMergeToleranceLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        XLIM_H = 1;
        prevwin = 1;
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            

            app.CallingApp = mainapp;
            [thresholds] = PreviewThreshold(mainapp.ECG_X, mainapp.ECG_Y, 97.5,mainapp.CalculatedFs,str2num(app.ThresholdWindowsecEditField.Value));
            plot(app.UIAxes,mainapp.ECG_X,mainapp.ECG_Y);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,mainapp.ECG_X,thresholds,'r--');

            ylim(app.UIAxes,[min(mainapp.ECG_Y) max(mainapp.ECG_Y)])
            xlim(app.UIAxes, [app.XLIM_H - app.prevwin app.XLIM_H])
            
            set(app.UIAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto')
            xticks(app.UIAxes,0:.5:mainapp.ECG_X(end));

            app.XlimSlider.Limits = [0 mainapp.ECG_X(end)];
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            GetBeatDetectOpts(app.CallingApp,str2num(app.BeatMergeToleranceEditField.Value), str2num(app.ThresholdWindowsecEditField.Value), str2num(app.ConvolutionWindowSizeEditField.Value))
            RunDetection(app.CallingApp)
            delete(app);
        end

        % Value changing function: XlimSlider
        function XlimSliderValueChanging(app, event)
            app.XLIM_H = event.Value;
            xlim(app.UIAxes,[app.XLIM_H - app.prevwin app.XLIM_H])
        end

        % Value changing function: PreviewSizeSecondsEditField
        function PreviewSizeSecondsEditFieldValueChanging(app, event)
            app.prevwin = str2num(event.Value);
            xlim(app.UIAxes, [app.XLIM_H - app.prevwin app.XLIM_H])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Preview')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BeatDetectionSettingsPanel = uipanel(app.UIFigure);
            app.BeatDetectionSettingsPanel.Title = 'Beat Detection Settings...';
            app.BeatMergeToleranceLabel = uilabel(app.BeatDetectionSettingsPanel);
            app.BeatMergeToleranceLabel.Text = 'Beat Merge Tolerance ';
            app.BeatMergeToleranceEditField = uieditfield(app.BeatDetectionSettingsPanel, 'text');
            app.BeatMergeToleranceEditField.Value = '1000';
            app.ThresholdWindowsecLabel = uilabel(app.BeatDetectionSettingsPanel);
            app.ThresholdWindowsecLabel.Text = 'Threshold Window (sec) ';
            app.ThresholdWindowsecEditField = uieditfield(app.BeatDetectionSettingsPanel, 'text');
            app.ThresholdWindowsecEditField.Value = '1';
            app.HeartRateCalculationSettingsPanel = uipanel(app.UIFigure);
            app.HeartRateCalculationSettingsPanel.Title = 'Heart Rate Calculation Settings...';
            app.ConvolutionWindowSizeEditFieldLabel = uilabel(app.HeartRateCalculationSettingsPanel);
            app.ConvolutionWindowSizeEditFieldLabel.Text = 'Convolution Window Size';
            app.ConvolutionWindowSizeEditField = uieditfield(app.HeartRateCalculationSettingsPanel, 'text');
            app.ConvolutionWindowSizeEditField.Value = '10';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.RunButton_2 = uibutton(app.UIFigure, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.XlimSliderLabel = uilabel(app.UIFigure);
            app.XlimSliderLabel.Text = 'Xlim';
            app.XlimSlider = uislider(app.UIFigure);
            app.XlimSlider.MajorTicks = [];
            app.XlimSlider.ValueChangingFcn = createCallbackFcn(app, @XlimSliderValueChanging, true);
            app.PreviewSizeSecondsEditFieldLabel = uilabel(app.UIFigure);
            app.PreviewSizeSecondsEditFieldLabel.Text = 'Preview Size (Seconds)';
            app.PreviewSizeSecondsEditField = uieditfield(app.UIFigure, 'text');
            app.PreviewSizeSecondsEditField.InputType = 'digits';
            app.PreviewSizeSecondsEditField.ValueChangingFcn = createCallbackFcn(app, @PreviewSizeSecondsEditFieldValueChanging, true);
            app.PreviewSizeSecondsEditField.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeatDetectionDlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end