classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TonaFlowUIFigure            matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        ImportECGMenu               matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        BeatsasCSVMenu              matlab.ui.container.Menu
        HeartRateasCSVMenu          matlab.ui.container.Menu
        RespirationRateasCSVMenu    matlab.ui.container.Menu
        ECGMenu                     matlab.ui.container.Menu
        FilterMenu                  matlab.ui.container.Menu
        CWTBandpassMenu             matlab.ui.container.Menu
        RunBeatDetectionMenu        matlab.ui.container.Menu
        ExtractRespirationMenu      matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        Ver019Label                 matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UpperXLimitSpinner_ECG      matlab.ui.control.Spinner
        UpperXLimitSpinnerLabel     matlab.ui.control.Label
        LowerXLimitSpinner_ECG      matlab.ui.control.Spinner
        LowerXLimitSpinnerLabel     matlab.ui.control.Label
        DataRemovalPanel            matlab.ui.container.Panel
        SelectDataforRemovalButton  matlab.ui.control.Button
        BeatDetectionPanel          matlab.ui.container.Panel
        RedoButton                  matlab.ui.control.Button
        UndoButton                  matlab.ui.control.Button
        AddHeartbeatsButton         matlab.ui.control.Button
        RemoveHeartbeatsButton      matlab.ui.control.Button
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %% ORIGINAL variables (these should remain immutable)
        
        %% Essentials
        ECG_Y % The RAW ECG Signal.
        ECG_X % The time component.
        CalculatedFs
        %% Heart Rate Calculation
        MergeTolerance % Beat Merging tolerance
        ThresholdWindow %Threshold Window size in seconds
        ConvSize %Convolution window size in seconds
        RecalcRate
        %% Data Removal
        RemovalIndices %Indices in which we are removing data. First column is start, second column is stop. 
        RemovalIndices_Beats % Same as above but for the beats 
        %% Beats
        HeartBeats
        %% HR
        HR
        %% Respiration
        RespRate
        RespComp %WT Transform of resp 
        RespFreqRange
        %% Other Stuff
        CURSOR % Data Cursor Mode Object for removal of beats. This lets us track selected objects on the axis. 
        PatchOb % The patch object for patching intervals of data to be removed

        HasBeatRun % Has the beat detection run? 
        %% Undo Redo functions
        StackHistory = []

        %% Waitbars
        CWTwaitbar
        Respwaitbar
    end
    
    properties (Access = private)
        XLIM % Property for X limits
    end
    
    methods (Access = private)
        %% Function to read the ECG in
        function ReadECG(app,path,file)

            % Read the ECG 
            dat = readtable(strcat(path,file));
            dat = dat.Variables;
            % Validate that it follows the correct format.
            if size(dat,2) < 2 
                clear dat;
                errordlg("The format in which your data is saved is not recognized. ECG not loaded. Please make sure your data follows this format: " + ...
                    "Two columns; First column being time, and second column being measurement.","Format Not Recognized")
            else
                % Assign to global vars
                app.ECG_Y = dat(:,2);
                app.ECG_X = dat(:,1);

                if ~(iscolumn(app.ECG_X) && iscolumn(app.ECG_Y))
                    if ~iscolumn(app.ECG_X)
                        app.ECG_X = app.ECG_X';
                    elseif ~iscolumn(app.ECG_Y)
                        app.ECG_Y = app.ECG_Y';
                    end
                end
                % Set 
                % Determine Fs and create X timeline
                app.CalculatedFs = round(1 / mean(diff(app.ECG_X)));
                % msgbox(sprintf("Sampling rate has been detected as %.3f Hz",app.CalculatedFs));
                % app.ECG_X = app.ECG_X(1)/app.CalculatedFs:1/app.CalculatedFs:app.ECG_X(end);
                
                xlim(app.UIAxes,[min(app.ECG_X) max(app.ECG_X)]);
                app.XLIM = [min(app.ECG_X) max(app.ECG_X)];
    
                % Update the app's lim spinners
                app.LowerXLimitSpinner_ECG.Value = app.XLIM(1);
                app.UpperXLimitSpinner_ECG.Value = app.XLIM(2);
            end

            
        end
        %% Function to Plot the raw ECG
        function PlotECG(app)
            plot(app.UIAxes,app.ECG_X,app.ECG_Y)
        end
        
        function RemoveBeat(app)
            % First save the beats in the stack
            app.EditStackHistory;


            cursorinfo = getCursorInfo(app.CURSOR);
            if isempty(cursorinfo)
                errordlg("There are no points selected!");
            else
                for i = 1:size(cursorinfo,2)
                    % get index
                    ix = cursorinfo(i).DataIndex;
                    c = cumsum(app.HeartBeats);
                    [u,o] = unique(c,'first');
                    m = find(u == ix);
                    ix = o(m);
                    % delete index from binary data
                    app.HeartBeats(ix) = 0;
                end
                % Delete heartbeat child from axis object
                delete(app.UIAxes.Children(1));
                % replot the heartbeats
                plot(app.UIAxes,app.ECG_X(app.HeartBeats == 1), app.ECG_Y(app.HeartBeats == 1),'ro');
            end

            % And then we recalculate the heart rate
            app.RecalculateHeartRate();

            % Save the beats in the stack one more time for redo 
            app.EditStackHistory;
        end
        %%
        function AddBeat(app)
            % Save in the stack history
            app.EditStackHistory;


            % Get the index
            cursorinfo = getCursorInfo(app.CURSOR);
            for i = 1:size(cursorinfo,2)
                ix = cursorinfo(i).DataIndex;
                app.HeartBeats(ix) = 1;
            end
            % Delete heartbeat child from axis object
            delete(app.UIAxes.Children(1));
            % replot the heartbeats
            plot(app.UIAxes,app.ECG_X(app.HeartBeats == 1), app.ECG_Y(app.HeartBeats == 1),'ro');
            % Delete the datatip that matlab displays
            dt = findobj(app.UIAxes,'Type','DataTip');
            delete(dt);

            % And then we recalculate the heart rate
            app.RecalculateHeartRate();

            % On emore time
            app.EditStackHistory;
        end
        
        function updatelims(app)
            app.UIAxes.XLim = app.XLIM;
        end
        
        function EditStackHistory(app)
            % Save the beats
            app.StackHistory = [app.StackHistory; {app.HeartBeats}];
            if length(app.StackHistory) > 5
                % Pop the first ie first save
                app.StackHistory(1) = [];
            end
        end
    end
    
    methods (Access = public)
        function results = GetBeatDetectOpts(app,mergetolerance,threshwin,convsize)
            app.MergeTolerance = mergetolerance;
            % Add other options as we go on
            app.ThresholdWindow = threshwin;
            app.ConvSize = convsize;
        end
        %%
        function results = RunDetection(app)
            %% Finally, run the beat detection algorithm
            app.HeartBeats = CalculateBeats(app,app.ECG_X,app.ECG_Y,app.CalculatedFs,app.MergeTolerance,app.ThresholdWindow);
            % Plot the beats
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.ECG_X(app.HeartBeats == 1), app.ECG_Y(app.HeartBeats == 1),'ro');

            %%Then calculate the heartrate.
            HR = CalculateHeartRate(app.HeartBeats,app.ECG_X,app.CalculatedFs,app.ConvSize);
            plot(app.UIAxes_2,app.ECG_X,HR)
            app.HR = HR;
            %%Lock the axes.
            linkaxes([app.UIAxes app.UIAxes_2 app.UIAxes_3],'x')
            %%Specify that the beat detection has been run 
            app.HasBeatRun = 1;
            % Enable buttons
            app.SelectDataforRemovalButton.Enable = "on";
            app.AddHeartbeatsButton.Enable = "on";
            app.RemoveHeartbeatsButton.Enable = "on";
            app.UndoButton.Enable = 'on';
            app.RedoButton.Enable = 'on';
        end


        function results = RecalculateHeartRate(app)
           % Find the indices in which there are nans
           nanx = find_nan_indices(app.HeartBeats);
           % Take out the NaN's and glue the heart rate together.
           tempBeats = app.HeartBeats(~isnan(app.HeartBeats));
           % Calculate the new heartrate with this truncated beats
           xt = 1/app.CalculatedFs:1/app.CalculatedFs:size(tempBeats,2)/app.CalculatedFs;
           recalcRate = CalculateHeartRate(tempBeats,xt,app.CalculatedFs,app.ConvSize);
           
           % Generate indices of NaNs using the indices we found and place
           % them back in.
           InsertNans={};
           for i = 1:size(nanx,2)
                InsertNans = nan(1,nanx{i}(2)-nanx{i}(1)+2); % why the heck does adding +2 work....
                firstHalf = [recalcRate(1:nanx{i}(1) - 1), InsertNans];
                secondHalf = recalcRate(nanx{i}(1)+1:end);
                recalcRate = [firstHalf secondHalf];
                % size([firstHalf secondHalf])
                % recalcRate = [recalcRate(1:nanx{i}(1) - 1), InsertNans, recalcRate(nanx{i}(2) + 1):recalcRate(end)]
           end
           % Clear the plot
           cla(app.UIAxes_2);

           % Replot the new HR
           if numel(app.ECG_X) > numel(recalcRate)
                nd =  numel(app.ECG_X) - numel(recalcRate);
                recalcRate(end: end+nd) = recalcRate(end);
           end

           plot(app.UIAxes_2,app.ECG_X,recalcRate)
        end
        
        function results = RunRespRate(app,comp) % comp is the wavelet transform
            app.RespRate = CalculateRespirationRate(app.ECG_X,comp,app.CalculatedFs);
            if length(app.RespRate) < length(app.ECG_X)
                df = length(app.ECG_X) - length(app.RespRate);
                nanpad = nan(df,1);
                app.RespRate = [app.RespRate nanpad];
            end
            plot(app.UIAxes_3, app.ECG_X,app.RespRate)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("Resources/Helper Functions/"))
            app.CURSOR = datacursormode(app.TonaFlowUIFigure); % Start a datacursor mode object 
            hold(app.UIAxes,'on')


        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)

        end

        % Menu selected function: ImportECGMenu
        function ImportECGMenuSelected(app, event)
            [file,path] = uigetfile("*.csv");

            if file ~= 0 % If user does not cancel the action
                app.ReadECG(path,file);  
                app.PlotECG;
    
                % Enable things now that we have the ECG Loaded
                % app.FilterMenu.Enable = "on"; % Feature not ready yet
                app.RunBeatDetectionMenu.Enable = "on";
            end
        end

        % Menu selected function: RunBeatDetectionMenu
        function RunBeatDetectionMenuSelected(app, event)
           dlgHandle =  BeatDetectionDlg(app);
        end

        % Button pushed function: RemoveHeartbeatsButton
        function RemoveHeartbeatsButtonPushed(app, event)
            app.RemoveBeat;
        end

        % Button pushed function: AddHeartbeatsButton
        function AddHeartbeatsButtonPushed(app, event)
          app.AddBeat;
        end

        % Button pushed function: SelectDataforRemovalButton
        function SelectDataforRemovalButtonPushed(app, event)
            ylm = app.UIAxes.YLim;
            %xlim(app.UIAxes,[min(app.ECG_X) max(app.ECG_X)]);
            r = drawrectangle(app.UIAxes);

            if ~isempty(r.Position) % r.position is empty if the user cancels

                rectbounds = [r.Position(1) r.Position(1) + r.Position(3)];
                c = [rectbounds(1) rectbounds(2) rectbounds(2) rectbounds(1);
                     ylm(1) ylm(1) ylm(2) ylm(2)];
  
                app.PatchOb = patch(app.UIAxes,c(1,:), c(2,:),'r');
                alpha(app.PatchOb,.1)
                % Get the data that is inside the boundaries  of the patch
                verts = app.PatchOb.Vertices;
                xbounds = verts(1:2,1);
                xbounds_inds = floor(xbounds*app.CalculatedFs);

                % Find the nearest heartbeat to the left and right. Then,
                % we will overlap them. This ensures that we do not create
                % false fluctuations in heart rate.
                ix = find(app.HeartBeats == 1);
                % Find leftward beat
                left = ix(ix< xbounds_inds(1));
                firstbeat = left(end)-1; 
                right = ix(ix > xbounds_inds(end));
                lastbeat = right(1)-1;
                % Change the xbounds_inds accordingly.
                xbounds_inds = [firstbeat;lastbeat];


                % Update the indices property 
                app.RemovalIndices = [app.RemovalIndices; xbounds_inds'];
                
                % Delete the handle for the rectangle
                delete(r);
    
                % NaN out data in the ECG Plot
                app.UIAxes.Children(end).YData(xbounds_inds(1):xbounds_inds(2)) = NaN;


                % Replace the plot with red lines where nan'd data
                removal_x = app.ECG_X(xbounds_inds(1):xbounds_inds(2));
                removal_y = repelem(app.ECG_Y(xbounds_inds(1)),size(removal_x,2));
                plot(app.UIAxes,removal_x,removal_y,'r--')
                % Remove heartbeats too
                % Find beats in between removal times (not inds bc..matlab)
                xd = app.UIAxes.Children(end-1).XData; % HBeats will always be the second 
                ix = find(xd <= xbounds(2) & xd >= xbounds(1));
                app.UIAxes.Children(end-1).YData(ix) = NaN;
                % Update the indices property fo rthe beats
                app.RemovalIndices_Beats = [app.RemovalIndices_Beats; [ix(1) ix(2)]];
                
    
                % Clear the last patch
                delete(app.PatchOb)
                
                % Nan out the data in the app.heartbeats
                app.HeartBeats(app.RemovalIndices(end,1):app.RemovalIndices(end,2)) = NaN; % Using end because it will always be the last updated indices
    
                % Recalculate and Re-Plot the heart rate.
                app.RecalculateHeartRate();
            end

        end

        % Value changed function: LowerXLimitSpinner_ECG
        function LowerXLimitSpinner_ECGValueChanged(app, event)
            value = app.LowerXLimitSpinner_ECG.Value;
            app.XLIM(1) = value;

            app.updatelims();
        end

        % Value changed function: UpperXLimitSpinner_ECG
        function UpperXLimitSpinner_ECGValueChanged(app, event)
            value = app.UpperXLimitSpinner_ECG.Value;
             app.XLIM(2) = value;

            app.updatelims();   
        end

        % Menu selected function: BeatsasCSVMenu
        function BeatsasCSVMenuSelected(app, event)
            if app.HasBeatRun
                if size(app.ECG_X,2) > size(app.HeartBeats,2)
                    x = app.ECG_X(1:end-1);
                else 
                    x = app.ECG_X;
                end
                
                if ~iscolumn(x)
                    x = x';
                end
                if ~iscolumn(app.HeartBeats)
                    app.HeartBeats = app.HeartBeats';
                end
                [file,path] = uiputfile(".csv");
                writematrix([x', app.HeartBeats'],strcat(path,file))
            else
                errordlg("No beat detection has been run!")
            end
        end

        % Callback function
        function DebugButtonPushed(app, event)

        end

        % Menu selected function: HeartRateasCSVMenu
        function HeartRateasCSVMenuSelected(app, event)
            if app.HasBeatRun
                [file,path] = uiputfile(".csv");

                if ~iscolumn(app.ECG_X)
                    app.ECG_X = app.ECG_X';
                end

                if ~iscolumn(app.HR)
                    app.HR = app.HR';
                end
                writematrix([app.ECG_X,app.HR],strcat(path,file));
            else
                errordlg("Heart rate has not been converted!")
            end
        end

        % Menu selected function: ExtractRespirationMenu
        function ExtractRespirationMenuSelected(app, event)

            % prompt = {'Lower Frequency Bound','Upper Frequency Bound'};
            % dlgtitle = 'Input Frequency Range';
            % fieldsize = [1 45; 1 45];
            % definput = {'.032','.1'};
            % answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            % 
            % [cfs, f] = cwt(app.ECG_Y,'bump',150,'VoicesPerOctive',32);
            % comp=icwt(cfs,[],f,[str2num(answer{1}) str2num(answer{2})]);
            % 
            % plot(app.UIAxes_3,app.ECG_X,comp)
            RespDLG(app);
        end

        % Menu selected function: CWTBandpassMenu
        function CWTBandpassMenuSelected(app, event)
            app.CWTwaitbar = uiprogressdlg(app.TonaFlowUIFigure,'message',"Calculating CWT...");
            CWTBandpassApp(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            % Grab the latest from the stack history
            app.HeartBeats = app.StackHistory{end-1};
            % Delete heartbeat child from axis object
            delete(app.UIAxes.Children(1));
            % replot the heartbeats
            plot(app.UIAxes,app.ECG_X(app.HeartBeats == 1), app.ECG_Y(app.HeartBeats == 1),'ro');
            
            app.RecalculateHeartRate;
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            % Grab the latest from the stack history
            app.HeartBeats = app.StackHistory{end};
            % Delete heartbeat child from axis object
            delete(app.UIAxes.Children(1));
            % replot the heartbeats
            plot(app.UIAxes,app.ECG_X(app.HeartBeats == 1), app.ECG_Y(app.HeartBeats == 1),'ro');
            
            app.RecalculateHeartRate;
        end

        % Menu selected function: RespirationRateasCSVMenu
        function RespirationRateasCSVMenuSelected(app, event)
            if isempty(app.RespRate)
                errordlg("There is no respiration rate!")
            else
                [file,path] = uiputfile(".csv");
                filepath = string(strcat(path,file));
                resp = [app.ECG_X app.RespRate];
                writetable(resp,filepath)
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TonaFlowUIFigure = uifigure('Visible', 'off');
            app.TonaFlowUIFigure.Name = 'TonaFlow';
            app.FileMenu = uimenu(app.TonaFlowUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportECGMenu = uimenu(app.FileMenu);
            app.ImportECGMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportECGMenuSelected, true);
            app.ImportECGMenu.Text = 'Import ECG';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export...';
            app.BeatsasCSVMenu = uimenu(app.ExportMenu);
            app.BeatsasCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @BeatsasCSVMenuSelected, true);
            app.BeatsasCSVMenu.Text = 'Beats as CSV';
            app.HeartRateasCSVMenu = uimenu(app.ExportMenu);
            app.HeartRateasCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @HeartRateasCSVMenuSelected, true);
            app.HeartRateasCSVMenu.Text = 'Heart Rate as CSV';
            app.RespirationRateasCSVMenu = uimenu(app.ExportMenu);
            app.RespirationRateasCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @RespirationRateasCSVMenuSelected, true);
            app.RespirationRateasCSVMenu.Text = 'Respiration Rate as CSV';
            app.ECGMenu = uimenu(app.TonaFlowUIFigure);
            app.ECGMenu.Text = 'ECG';
            app.FilterMenu = uimenu(app.ECGMenu);
            app.FilterMenu.Text = 'Filter';
            app.CWTBandpassMenu = uimenu(app.FilterMenu);
            app.CWTBandpassMenu.MenuSelectedFcn = createCallbackFcn(app, @CWTBandpassMenuSelected, true);
            app.CWTBandpassMenu.Text = 'CWT Bandpass';
            app.RunBeatDetectionMenu = uimenu(app.ECGMenu);
            app.RunBeatDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @RunBeatDetectionMenuSelected, true);
            app.RunBeatDetectionMenu.Enable = 'off';
            app.RunBeatDetectionMenu.Text = 'Run Beat Detection';
            app.ExtractRespirationMenu = uimenu(app.ECGMenu);
            app.ExtractRespirationMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractRespirationMenuSelected, true);
            app.ExtractRespirationMenu.Text = 'Extract Respiration';
            app.HelpMenu = uimenu(app.TonaFlowUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.TonaFlowUIFigure);
            title(app.UIAxes, 'ECG')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.TonaFlowUIFigure);
            title(app.UIAxes_2, 'Heart Rate')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.TonaFlowUIFigure);
            title(app.UIAxes_3, 'Respiration')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YTick = [];
            app.BeatDetectionPanel = uipanel(app.TonaFlowUIFigure);
            app.BeatDetectionPanel.Title = 'Beat Detection ';
            app.RemoveHeartbeatsButton = uibutton(app.BeatDetectionPanel, 'push');
            app.RemoveHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveHeartbeatsButtonPushed, true);
            app.RemoveHeartbeatsButton.Enable = 'off';
            app.RemoveHeartbeatsButton.Text = 'Remove Heartbeat(s)';
            app.AddHeartbeatsButton = uibutton(app.BeatDetectionPanel, 'push');
            app.AddHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @AddHeartbeatsButtonPushed, true);
            app.AddHeartbeatsButton.Enable = 'off';
            app.AddHeartbeatsButton.Text = 'Add Heartbeat(s)';
            app.UndoButton = uibutton(app.BeatDetectionPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = 'Undo';
            app.RedoButton = uibutton(app.BeatDetectionPanel, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Enable = 'off';
            app.RedoButton.Text = 'Redo';
            app.DataRemovalPanel = uipanel(app.TonaFlowUIFigure);
            app.DataRemovalPanel.Title = 'Data Removal';
            app.SelectDataforRemovalButton = uibutton(app.DataRemovalPanel, 'push');
            app.SelectDataforRemovalButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataforRemovalButtonPushed, true);
            app.SelectDataforRemovalButton.Enable = 'off';
            app.SelectDataforRemovalButton.Text = 'Select Data for Removal';
            app.LowerXLimitSpinnerLabel = uilabel(app.TonaFlowUIFigure);
            app.LowerXLimitSpinnerLabel.Text = 'Lower X Limit';
            app.LowerXLimitSpinner_ECG = uispinner(app.TonaFlowUIFigure);
            app.LowerXLimitSpinner_ECG.ValueChangedFcn = createCallbackFcn(app, @LowerXLimitSpinner_ECGValueChanged, true);
            app.UpperXLimitSpinnerLabel = uilabel(app.TonaFlowUIFigure);
            app.UpperXLimitSpinnerLabel.Text = 'Upper X Limit';
            app.UpperXLimitSpinner_ECG = uispinner(app.TonaFlowUIFigure);
            app.UpperXLimitSpinner_ECG.ValueChangedFcn = createCallbackFcn(app, @UpperXLimitSpinner_ECGValueChanged, true);
            app.Image = uiimage(app.TonaFlowUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'TF Logo.png');
            app.Ver019Label = uilabel(app.TonaFlowUIFigure);
            app.Ver019Label.Text = 'Ver. 0.1.9';
            % Show the figure after all components are created
            app.TonaFlowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TonaFlowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TonaFlowUIFigure)
        end
    end
end