classdef resptest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        lowerEditField       matlab.ui.control.EditField
        lowerEditFieldLabel  matlab.ui.control.Label
        upperEditField       matlab.ui.control.EditField
        upperEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bounds % Description
        yy
        xx
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            1

            app.bounds = [str2num(app.lowerEditField.Value) str2num(app.upperEditField.Value)];
            
            app.yy = mainapp.ECG_Y - min(mainapp.ECG_Y);
            app.xx = mainapp.ECG_X;

            [cfs, f] = cwt(mainapp.ECG_Y,'bump',150,'VoicesPerOctive',32);
            comp=icwt(cfs,[],f,app.bounds);

            cc = comp + mean(app.yy); 
            
            plot(app.UIAxes,app.xx,app.yy)
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.xx,cc);

        end

        % Value changing function: upperEditField
        function upperEditFieldValueChanging(app, event)
          
        end

        % Value changing function: lowerEditField
        function lowerEditFieldValueChanging(app, event)
    
        end

        % Value changed function: upperEditField
        function upperEditFieldValueChanged(app, event)
            app.bounds(2) = str2num(app.upperEditField.Value);
            [cfs, f] = cwt(app.yy,'bump',150,'VoicesPerOctive',32);
            comp=icwt(cfs,[],f,app.bounds);

            cc = comp + max(app.yy);
            cla(app.UIAxes)
            plot(app.UIAxes,app.xx,app.yy)
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.xx,cc);
        end

        % Value changed function: lowerEditField
        function lowerEditFieldValueChanged(app, event)
            app.bounds(1) = str2num(app.lowerEditField.Value);
            [cfs, f] = cwt(app.yy,'bump',150,'VoicesPerOctive',32);
            comp=icwt(cfs,[],f,app.bounds);

            cc = comp + max(app.yy);
            cla(app.UIAxes)
            plot(app.UIAxes,app.xx,app.yy)
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.xx,cc);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.upperEditFieldLabel = uilabel(app.UIFigure);
            app.upperEditFieldLabel.Text = 'upper';
            app.upperEditField = uieditfield(app.UIFigure, 'text');
            app.upperEditField.ValueChangedFcn = createCallbackFcn(app, @upperEditFieldValueChanged, true);
            app.upperEditField.ValueChangingFcn = createCallbackFcn(app, @upperEditFieldValueChanging, true);
            app.upperEditField.Value = '.1';
            app.lowerEditFieldLabel = uilabel(app.UIFigure);
            app.lowerEditFieldLabel.Text = 'lower';
            app.lowerEditField = uieditfield(app.UIFigure, 'text');
            app.lowerEditField.ValueChangedFcn = createCallbackFcn(app, @lowerEditFieldValueChanged, true);
            app.lowerEditField.ValueChangingFcn = createCallbackFcn(app, @lowerEditFieldValueChanging, true);
            app.lowerEditField.Value = '.032';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resptest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end